classdef Prange_Positioning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        v10UIFigure                     matlab.ui.Figure
        ObservationTimeLabel            matlab.ui.control.Label
        yEditFieldLabel                 matlab.ui.control.Label
        yEditField                      matlab.ui.control.NumericEditField
        mEditFieldLabel                 matlab.ui.control.Label
        mEditField                      matlab.ui.control.NumericEditField
        dEditFieldLabel                 matlab.ui.control.Label
        dEditField                      matlab.ui.control.NumericEditField
        hEditFieldLabel                 matlab.ui.control.Label
        hEditField                      matlab.ui.control.NumericEditField
        mEditField_2Label               matlab.ui.control.Label
        mEditField_2                    matlab.ui.control.NumericEditField
        sEditFieldLabel                 matlab.ui.control.Label
        sEditField                      matlab.ui.control.NumericEditField
        ofilepathEditFieldLabel         matlab.ui.control.Label
        ofilepathEditField              matlab.ui.control.EditField
        nfilepathEditFieldLabel         matlab.ui.control.Label
        nfilepathEditField              matlab.ui.control.EditField
        mfilepathEditFieldLabel         matlab.ui.control.Label
        mfilepathEditField              matlab.ui.control.EditField
        TroposphericmodelDropDownLabel  matlab.ui.control.Label
        TroposphericmodelDropDown       matlab.ui.control.DropDown
        kmGeodeticHstationEditField_2Label  matlab.ui.control.Label
        kmGeodeticHstationEditField_2   matlab.ui.control.EditField
        IONALPHAEditFieldLabel          matlab.ui.control.Label
        IONALPHAEditField               matlab.ui.control.EditField
        IONBETAEditFieldLabel           matlab.ui.control.Label
        IONBETAEditField                matlab.ui.control.EditField
        OutputLabel                     matlab.ui.control.Label
        ObservablesatelliteEditFieldLabel  matlab.ui.control.Label
        ObservablesatelliteEditField    matlab.ui.control.EditField
        StationcoordinatesLabel         matlab.ui.control.Label
        XrEditFieldLabel                matlab.ui.control.Label
        XrEditField                     matlab.ui.control.NumericEditField
        ZrEditFieldLabel                matlab.ui.control.Label
        ZrEditField                     matlab.ui.control.NumericEditField
        YrEditFieldLabel                matlab.ui.control.Label
        YrEditField                     matlab.ui.control.NumericEditField
        StartButton                     matlab.ui.control.Button
        unitmLabel                      matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        PseudorangeDropDownLabel        matlab.ui.control.Label
        PseudorangeDropDown             matlab.ui.control.DropDown
        NotesTextAreaLabel              matlab.ui.control.Label
        NotesTextArea                   matlab.ui.control.TextArea
    end


    methods (Access = private)
    
        function [P0,T,e0]=readfilem(app,file,hour,minute,second)
%[P0,T,e0]从m文件中读取对应时刻的气压（mbar）、温度（K）、湿度（%）
if mod(minute,5)>2
    minute=minute-mod(minute,5)+5;
elseif mod(minute,5)==2
       if second>30
           minute=minute-mod(minute,5)+5;
       else
           minute=minute-mod(minute,5);
       end
elseif mod(minute,5)<=2
       minute=minute-mod(minute,5);
end
if minute==60
   hour=hour+1;
   minute=0;
end
data=importdata(file,'\n');
for i=1:length(data(:,:))
    if eval(data{i,:}(11:12))==hour&&eval(data{i,:}(14:15))==minute
        P0=eval(data{i,:}(20:25));
        T=273.16+eval(data{i,:}(29:32));
        e0=eval(data{i,:}(36:37));
    end
end
            
        end


        function [PRN,C1]=readfileo(app,file,hour,minute,second,choosePrange)
if second<15
    second=0;
elseif second<45
    second=30;
else
    minute=minute+1;
    if minute==60
        minute=0;
        hour=hour+1;
    end
    second=0;
end
if hour==24
   hour=23;
   minute=59;
   second=30;
end
data=importdata(file,'\n');
%寻找所需时间的数据位置

for i=1:length(data(:,:))
    if eval(data{i,1}(11:12))==hour&&eval(data{i,1}(14:15))==minute&&eval(data{i,1}(16:26))==second
        SatNum=eval(data{i,1}(31:32));
        data_out=data(i:i+SatNum,1);
        SatDiscard=zeros(1,SatNum);
        for i=1:SatNum
            
            if choosePrange==1
                jdg=length(data_out{i+1}(:))<35||data_out{i+1}(41)==' ';
            elseif choosePrange==2
                jdg=length(data_out{i+1}(:))<51||data_out{i+1}(57)==' ';
            elseif choosePrange==3
                jdg=length(data_out{i+1}(:))<67||data_out{i+1}(74)==' ';
            end
            
           if  jdg
               SatDiscard(i)=i;
           end
        end
        for i=1:3:3*SatNum
        if ismember((i+2)./3,SatDiscard)
            continue;
        else
            PRN((i+2)./3)=eval(data_out{1,1}(33+i:33+i+1));
        end
        end
        PRN(PRN==0)=[];
        for i=1:SatNum
        if ismember(i,SatDiscard)
            continue;
        else
            startF=35+(choosePrange-1)*16;
            endF=46+(choosePrange-1)*16;
            C1(i)=eval(data_out{i+1,:}(startF:endF));
%             P1(i)=eval(data{i+1}(51:62));
%           P2(i)=eval(data{i+1}(67:78));
        end
        end
        C1(C1==0)=[];
    break;
    end
end
            
        end


function [Xs,Ys,Zs,Deltat]=readatandcomp(app,file,PRNwanted,tob,t)
%file        读入的文件需去除头文件部分，获得卫星号顶行的数据
%PRNwanted   想要读取数据的卫星号PRN
%tob         观测时刻的年月日时分秒，年取后两位
%t           观测时刻对应的GPST，可缺省
data=importdata(file);
j=1;
for i=1:8:length(data(:,:))
    temp=data(i:i+7,:);
    if str2double(temp{1,1}(1:2))==PRNwanted
        finded(j,:)=temp';
        j=j+1;
    end
end
findedNum=length(finded(:,1));
%取整小时观测时刻UTC，用于寻找观测时刻当天合适的卫星参考时刻
if mod(tob(4),2)~=0
    if tob(5)>0
        th=tob(4)+1;
    else
    th=tob(4)-mod(tob(4),2);
    end
else
    th=tob(4);
end
if  th==24
    th=0;
    th1=24;
    for i=1:findedNum
       jde(i)=(str2double(finded{i,1}(10:11))==(tob(3)+1)&&str2double(finded{i,1}(13:14))==th) || str2double(finded{i,1}(13:14))+1==th1;
    end
else
    th1=th;
    for i=1:findedNum
       jde(i)=str2double(finded{i,1}(13:14))==th || str2double(finded{i,1}(13:14))+1==th1;
    end
end
tmp=[];
for i=1:findedNum
    if jde(i)
        tmp=finded(i,:)';
        data=tmp{1,1};
        PRN=data(1:2);year=str2double(data(4:5));month=str2double(data(7:8));day=str2double(data(10:11));
        hour=str2double(data(13:14));mintue=str2double(data(16:17));second=str2double(data(19:22));
        a0=eval(data(23:41));a1=eval(data(42:60));a2=eval(data(61:79));
        
        Cuc=eval(tmp{3,1}(4:22));toe=eval(tmp{4,1}(4:22));i0=eval(tmp{5,1}(4:22));iDOT=eval(tmp{6,1}(4:22));
        Crs=eval(tmp{2,1}(23:41));e=eval(tmp{3,1}(23:41));Cic=eval(tmp{4,1}(23:41));Crc=eval(tmp{5,1}(23:41));
        Delta_n=eval(tmp{2,1}(42:60));Cus=eval(tmp{3,1}(42:60));OMEGA=eval(tmp{4,1}(42:60));omega=eval(tmp{5,1}(42:60));
        M0=eval(tmp{2,1}(61:79));sqrtA=eval(tmp{3,1}(61:79));Cis=eval(tmp{4,1}(61:79));OMEGA_DOT=eval(tmp{5,1}(61:79));
%         %将要计算的卫星数据写入data_select.txt，用户可自行查看
%         fileID = fopen('n_selected.txt','w');
%         formatSpec = '%s \n';
%         [nrows,~] = size(tmp);
%         for row = 1:nrows
%             fprintf(fileID,formatSpec,tmp{row,:}); % 注意此处不用{}，而是（）时，得到的字符串酱油引号，需注意
%         end
%         fclose(fileID);
        break
    end
end
if isempty(tmp)
    disp('n文件中无匹配数据')
    close;
end
% clear data th i j tmp finded thf formatSpec nrows row fileID;
% %将有关参数读入工作区
% [data1,data2,data3,data4]=textread('n_selected.txt','%f%f%f%f','headerlines', 1);
% Cuc=data1(2);toe=data1(3);i0=data1(4);iDOT=data1(5);
% Crs=data2(1);e=data2(2);Cic=data2(3);Crc=data2(4);
% Delta_n=data3(1);Cus=data3(2);OMEGA=data3(3);omega=data3(4);
% M0=data4(1);sqrtA=data4(2);Cis=data4(3);OMEGA_DOT=data4(4);
% data=importdata('n_selected.txt');
% data=data{1,1};
% PRN=data(1:2);year=str2double(data(4:5));month=str2double(data(7:8));day=str2double(data(10:11));
% hour=str2double(data(13:14));mintue=str2double(data(16:17));second=str2double(data(19:22));
% a0=eval(data(23:41));a1=eval(data(42:60));a2=eval(data(61:79));
% clear data data1 data2 data3 data4 ;
if nargin < 5
    [~, t]=UTC2GPST(app,tob(1),tob(2),tob(3),tob(4),tob(5),tob(6));
end
[~, toc]=UTC2GPST(app,year,month,day,hour,mintue,second);
[Xs,Ys,Zs,Deltat]=comsatpos(app,t,toc,a0,a1,a2,Crs,Delta_n,M0,Cuc,e,Cus,sqrtA,toe,Cic,OMEGA,Cis,i0,Crc,omega,OMEGA_DOT,iDOT);
end


function [Xs,Ys,Zs,Deltat]=comsatpos(app,t,toc,a0,a1,a2,Crs,Delta_n,M0,Cuc,e,Cus,sqrtA,toe,Cic,OMEGA,Cis,i0,Crc,omega,OMEGA_DOT,iDOT)
%摄动改正后的升交距角uk、卫星矢径rk和轨道倾角ik
%卫星在轨道面坐标系中的坐标x,y
%发射时刻升交点的经度L
%卫星在地固坐标系下坐标Xs,Ys,Zs
%观测时间t，参考时刻toe，a0,a1,a2
%16参数
%Crs Delta_n M0  
%Cuc e Cus sqrtA 
%toe Cic  OMEGA Cis
%i0 Crc omega OMEGA_DOT  iDOT
%
%以下为计算代码
%1.计算卫星运行的平均角速度
n0=sqrt(3.986005E+14)/(sqrtA.^3);
n=n0+Delta_n;
%2.计算信号发射时卫星的平近点角
Deltat=a0+a1*(t-toc)+a2*(t-toc).^2;%t为未做钟差改正的观测时刻
t=t-Deltat;%更新t为做钟差改正后的值
tk=t-toe;%归化时间
% if tk>302400
%     tk=tk-604800;
% elseif tk<-302400
%     tk=tk+604800;
% else
%     tk=tk+0;
% end
Mk=M0+n*tk;
%3.计算偏近点角（迭代）
%E=M+e*sin(E);
ed(1)=Mk;
for i=1:4
   ed(i+1)=Mk+e*sin(ed(i));
end
Ek=ed(5);
%4.计算真近点角
Vk=atan2(sqrt(1-e.^2)*sin(Ek),(cos(Ek)-e));
%5.计算升交距角（未经改进时）
u=omega+Vk;
%6.计算摄动改正项
deltau=Cuc*cos(2*u)+Cus*sin(2*u);
deltar=Crc*cos(2*u)+Crs*sin(2*u);
deltai=Cic*cos(2*u)+Cis*sin(2*u);
%7.计算摄动改正后的升交距角uk、卫星矢径rk和轨道倾角ik
uk=u+deltau;
rk=(sqrtA.^2)*(1-e*cos(Ek))+deltar;
ik=i0+deltai+iDOT*tk;
%8.计算卫星在轨道面坐标系中的坐标
x=rk*cos(uk);
y=rk*sin(uk);
%9.计算发射时刻升交点的经度67
L=OMEGA+(OMEGA_DOT-7.29211567E-5)*tk-7.292115E-5*toe;
%10.计算卫星在地固坐标系下坐标
Xs=x*cos(L)-y*cos(ik)*sin(L);
Ys=x*sin(L)+y*cos(ik)*cos(L);
Zs=y*sin(ik);
% DeltaX=Xs-(-20274509.129)
% DeltaY=Ys-(13349329.456)
% DeltaZ=Zs-(-10661361.857)
end


function [SatUsed,Xr]=fuc_of_Prange_Positioning(app,tob,geodeticHstation,chooseTropo,Alpha,Beta,filen,fileo,filem,choosePrange)
%----------------------------配置参数------------------------------------
% tob=[01,9,4,9,30,0];                                     %观测时刻的UTC时，年取后两位
% geodeticHstation=93.4e-3;%NaN                            %测站大地高km，如果不知道填NaN
% chooseTropo=2;                                           %采用的对流层模型1:简化霍普菲尔德（Hopfield）改正模型 2:萨斯塔莫宁（Saastamoinen）改正模型
% Alpha=[0.2235D-07  0.2235D-07 -0.1192D-06 -0.1192D-06];  %导航电文头文件中的电离层Alpha
% Beta=[0.1290D+06  0.4915D+05 -0.1966D+06  0.3277D+06];   %导航电文头文件中的电离层Beta
% filen='wuhn2470_clearhdr.01n';                           %去头的n文件
% fileo='wuhn2470_clearhdr.01o';                           %去头的o文件
% filem='wuhn2470_clearhdr.01m';                           %去头的m文件
%------------------------------------------------------------------------
%----------------------------常数区--------------------------------------
omegaE=7.29211511467e-5;                                 %地球自转角速度rad/s
cv=299792458;                                            %光速m/s
a=6378137;                                               %WGS84椭球半长轴m
finv=298.2572236;                                        %WGS84椭球扁率倒数
if chooseTropo==1
[P0,T,e0]=readfilem(app,filem,tob(4),tob(5),tob(6));         %从m文件中读取对应时刻的气压（mbar）、温度（K）、湿度（%）
end
%------------------------------------------------------------------------
%以下为计算过程
%1、在O文件中提取四颗以上卫星的伪距观测值。
[PRN,C1]=readfileo(app,fileo,tob(4),tob(5),tob(6),choosePrange);%去头的o文件
SatNum=length(PRN(:));
%2、在N文件中提取对应卫星的数据。
[~,GPST]=UTC2GPST(app,tob(1),tob(2),tob(3),tob(4),tob(5),tob(6));
for i=1:SatNum
    [~,~,~,deltat(i)]=readatandcomp(app,filen,PRN(i),tob);
end
%3、程序初始化，置测站概略位置为Xr，接收机钟差初值为dt。
X0=[0;0;0;0];
%4、选择epoch中一颗卫星Si，设其伪距为GSiC1
 while 1   
     for i=1:SatNum
%5、计算卫星Si的卫星钟差dt
%由计算卫星坐标时带出
%6、计算卫星-接收机的近似几何距离Rs
%（1）根据接收时间和伪距 计算信号发射时间
        Deltat(i)=C1(i)./cv;
        Tems(i)=GPST-(Deltat(i)+deltat(i));
%（2）计算发射时刻的卫星坐标 ，并对卫星坐标进行地球自转改正
        [Xs(i),Ys(i),Zs(i),deltat(i)]=readatandcomp(app,filen,PRN(i),tob,Tems(i));
        Prec=[cos(omegaE*Deltat(i)),sin(omegaE*Deltat(i)),0;
            -sin(omegaE*Deltat(i)),cos(omegaE*Deltat(i)),0;
            0,0,1]*[Xs(i);Ys(i);Zs(i)];
%（3）计算近似几何距离
        Rs=sqrt((Prec(1,1)-X0(1,1))^2+(Prec(2,1)-X0(2,1))^2+(Prec(3,1)-X0(3,1))^2);
%7、计算对流层延迟 dtrop
        dx=Prec-X0(1:3,1);
        [~, E1(i), ~] = topocent(app,X0(1:3,1),dx);
        if isnan(geodeticHstation)
           [~,~,h] = togeod(app,a,finv,X0(1,1),X0(2,1),X0(3,1)); 
           geodeticHstation=h*10^(-3);
        end
        if chooseTropo==1
            dtrop = tropo(app,sind(E1(i)),geodeticHstation,P0,T,e0,geodeticHstation,geodeticHstation,geodeticHstation);
        elseif chooseTropo==2
            dtrop = tropo_error_correction(app,E1(i),geodeticHstation);
        end
% dtrop=0;%暂不考虑dtrop
%8、计算电离层延迟 diono
        diono=Error_Ionospheric_Klobuchar(app,X0(1:3,1)',[Xs(i);Ys(i);Zs(i)]',Alpha,Beta,GPST);
% diono=0;%暂不考虑dtrop
%9、求卫星Si在观测方程中的余数项
        l(i)=C1(i)-Rs+cv*deltat(i)-dtrop-diono+0;
%10、求卫星Si方向余弦
        b0(i)=(X0(1,1)-Prec(1,1))./Rs;
        b1(i)=(X0(2,1)-Prec(2,1))./Rs;
        b2(i)=(X0(3,1)-Prec(3,1))./Rs;
        b3(i)=1;
    end
% 11、选择Epoch中的下一颗卫星，设其伪距为ρS。
% 12、重复5--11步，计算每颗卫星的系数和余数项。
%13、将所有卫星的系数组成误差方程，以（x,y,z,cdtr）为未知数进行求解，形式为:AX=L
    A=[b0',b1',b2',b3'];L=l';
%14、求解： X(i)=(inv(A'*P*A))*(A'*P*L)，得出定位结果。
    X=(inv(A'*A))*(A'*L);
    V=A*X-L;
% P权阵
% P=[sind(E1(1))^2,0,0,0,0,0;
%     0,sind(E1(2))^2,0,0,0,0;
%     0,0,sind(E1(3))^2,0,0,0;
%     0,0,0,sind(E1(4))^2,0,0;
%     0,0,0,0,sind(E1(5))^2,0;
%     0,0,0,0,0,sind(E1(6))^2];
% X=(inv(A'*P*A))*(A'*P*L);
    Xi=X0+X;%这一步重要
% 15、与X0进行比较，判断位置差值。
    if abs(X(1,1))>0.001||abs(X(2,1))>0.001||abs(X(3,1))>0.001
       X0=Xi;
    else
       X0=Xi;
       break;
    end
end
% %16、输出满足条件的Xi。
Xr=X0(1:3,1);
SatUsed=num2str(PRN);
% deltax=X0(1:3,1)-[-2267749.30600679;5009154.2824012134;3221290.677045021]
% deltas=sqrt(deltax(1,1)^2+deltax(2,1)^2+deltax(3,1)^2)
% E1
% P=zeros(SatNum,SatNum);
% for i=1:SatNum
%     P(i,i)=sind(E1(i))^2;
% end
% Qx=inv(A'*P*A);
% GDOP=sqrt(Qx(1,1)+Qx(2,2)+Qx(3,3)+Qx(4,4))
% PDOP=sqrt(Qx(1,1)+Qx(2,2)+Qx(3,3))
% TDOP=sqrt(Qx(4,4))
% HDOP=sqrt(Qx(1,1)^2+Qx(2,2)^2+Qx(3,3)^2+Qx(4,4)^2)
% VDOP=sqrt(Qx(1,1)^2+Qx(2,2)^2+Qx(3,3)^2+Qx(4,4)^2)
end


function [dphi,dlambda,h] = togeod(app,a,finv,X,Y,Z)
%TOGEOD   Subroutine to calculate geodetic coordinates
%         latitude, longitude, height given Cartesian
%         coordinates X,Y,Z, and reference ellipsoid
%         values semi-major axis (a) and the inverse
%         of flattening (finv).

%  The units of linear parameters X,Y,Z,a must all agree (m,km,mi,ft,..etc)
%  The output units of angular quantities will be in decimal degrees
%  (15.5 degrees not 15 deg 30 min).  The output units of h will be the
%  same as the units of X,Y,Z,a.

%  Copyright (C) 1987 C. Goad, Columbus, Ohio
%  Reprinted with permission of author, 1996
%  Fortran code translated into MATLAB
%  Kai Borre 03-30-96
% Changed according to Matlab ver. 6.5.1, 9 February 2004

h = 0;
tolsq = 1.e-10;
maxit = 1000;
% compute radians-to-degree factor
rtd = 180/pi;
% compute square of eccentricity
if finv < 1.e-20
   esq = 0;
else  
   esq = (2-1/finv)/finv; 
end
oneesq = 1-esq;
% first guess
% P is distance from spin axix
P = sqrt(X^2+Y^2);
% direct calculation of longitude
if P > 1.e-20
   dlambda = atan2(Y,X)*rtd;
else
   dlambda = 0; 
end
if (dlambda < 0)
   dlambda = dlambda + 360;
end
% r is distance from origin (0,0,0)
r = sqrt(P^2+Z^2);
if r > 1.e-20
   sinphi = Z/r;
else
   sinphi = 0; 
end
dphi = asin(sinphi);
% initial value of height  =  distance from origin minus
% approximate distance from origin to surface of ellipsoid
if r < 1.e-20
   h = 0;
   return;
end
h = r-a*(1-sinphi*sinphi/finv);
% iterate
for i = 1:maxit
   sinphi = sin(dphi);
   cosphi = cos(dphi);
   % compute radius of curvature in prime vertical direction
   N_phi = a/sqrt(1-esq*sinphi*sinphi);
   % compute residuals in P and Z
   dP = P - (N_phi + h) * cosphi;
   dZ = Z - (N_phi*oneesq + h) * sinphi;
   % update height and latitude
   h = h+(sinphi*dZ+cosphi*dP);
   dphi = dphi+(cosphi*dZ-sinphi*dP)/(N_phi + h);
   % test for convergence
   if (dP*dP + dZ*dZ < tolsq)
      break;
   end
   
   % Not Converged--Warn user
   if i == maxit
      fprintf([' Problem in TOGEOD, did not converge in %2.0f',...
            ' iterations\n'],i)
   end
end
dphi = dphi*rtd;
%%%%%%%% end togeod.m  %%%%%%%%%%%%%%%%%%%%%%
end


function [Az, El, D] = topocent(app,X,dx)
%TOPOCENT  Transformation of vector dx into topocentric coordinate
%          system with origin at X.矢量dx到原点为X的中心坐标系的变换。
%          Both parameters are 3 by 1 vectors.两个参数都是3×1的向量。
%          Output: D    vector length in units like the input向量长度的单位和输入一样
%                  Az   azimuth from north positive clockwise, degrees方位从北正顺时针，角度
%                  El   elevation angle, degrees仰角,度

%Kai Borre 11-24-96
%Copyright (c) by Kai Borre
%$Revision: 1.0 $  $Date: 1997/09/26  $

dtr = pi/180;
[phi,lambda,h] = togeod(app,6378137,298.257223563,X(1),X(2),X(3));
cl = cos(lambda*dtr); sl = sin(lambda*dtr);
cb = cos(phi*dtr); sb = sin(phi*dtr);
F = [-sl -sb*cl cb*cl;
      cl -sb*sl cb*sl;
       0    cb   sb];
local_vector = F'*dx;
E = local_vector(1);
N = local_vector(2);
U = local_vector(3);
hor_dis = sqrt(E^2+N^2);
if hor_dis < 1.e-20
   Az = 0;
   El = 90;
else
   Az = atan2(E,N)/dtr;
   El = atan2(U,hor_dis)/dtr;
end
if Az < 0
   Az = Az+360;
end
D = sqrt(dx(1)^2+dx(2)^2+dx(3)^2);
%%%%%%%%% end topocent.m %%%%%%%%%
end


function ddr = tropo(app,sinel,hsta,p,tkel,hum,hp,htkel,hhum)
%TROPO  Calculation of tropospheric correction.
%       The range correction ddr in m is to be subtracted from
%       pseudo-ranges and carrier phases

% sinel    sin of elevation angle of satellite
% hsta     height of station in km
% p        atmospheric pressure in mb at height hp
% tkel     surface temperature in degrees Kelvin at height htkel
% hum      humidity in % at height hhum
% hp       height of pressure measurement in km
% htkel    height of temperature measurement in km
% hhum     height of humidity measurement in km

% Reference
% Goad, C.C. & Goodman, L. (1974) A Modified Tropospheric
% Refraction Correction Model. Paper presented at the
% American Geophysical Union Annual Fall Meeting, San
% Francisco, December 12-17

% A Matlab reimplementation of a C code from driver.
% Kai Borre 06-28-95

a_e = 6378.137;     % semi-major axis of earth ellipsoid
b0 = 7.839257e-5;
tlapse = -6.5;
tkhum = tkel+tlapse*(hhum-htkel);
atkel = 7.5*(tkhum-273.15)/(237.3+tkhum-273.15);
e0 = 0.0611*hum*10^atkel;
tksea = tkel-tlapse*htkel;
em = -978.77/(2.8704e6*tlapse*1.0e-5);
tkelh = tksea+tlapse*hhum;
e0sea = e0*(tksea/tkelh)^(4*em);
tkelp = tksea+tlapse*hp;
psea = p*(tksea/tkelp)^em;
if sinel < 0
   sinel = 0; 
end
tropo0 = 0;
done = 'FALSE';
refsea = 77.624e-6/tksea;
htop = 1.1385e-5/refsea;
refsea = refsea*psea;
ref = refsea*((htop-hsta)/htop)^4;
while 1
   rtop = (a_e+htop)^2-(a_e+hsta)^2*(1-sinel^2);
   if rtop < 0, rtop = 0; end  % check to see if geometry is crazy
   rtop = sqrt(rtop)-(a_e+hsta)*sinel;
   a = -sinel/(htop-hsta);
   b = -b0*(1-sinel^2)/(htop-hsta);
   rn = zeros(8,1);
   for i = 1:8
      rn(i) = rtop^(i+1); 
   end
   alpha = [2*a, 2*a^2+4*b/3, a*(a^2+3*b),...
               a^4/5+2.4*a^2*b+1.2*b^2, 2*a*b*(a^2+3*b)/3,...
                             b^2*(6*a^2+4*b)*1.428571e-1, 0, 0];
   if b^2 > 1.0e-35, alpha(7) = a*b^3/2; alpha(8) = b^4/9; end
   dr = rtop;
   dr = dr+alpha*rn;
   tropo0 = tropo0+dr*ref*1000;
   if done == 'TRUE ', ddr = tropo0; break; end
   done = 'TRUE ';
   refsea = (371900.0e-6/tksea-12.92e-6)/tksea;
   htop = 1.1385e-5*(1255/tksea+0.05)/refsea;
   ref = refsea*e0sea*((htop-hsta)/htop)^4;
end
%%%%%%%%% end tropo.m  %%%%%%%%%%%%%%%%%%%
end


function [corr] = tropo_error_correction(app,el, h)

% SYNTAX:
%   [corr] = tropo_error_correction(el, h);
%
% INPUT:
%   el = satellite elevation
%   h  = receiver ellipsoidal height
%
% OUTPUT:
%   corr = tropospheric error correction
%
% DESCRIPTION:
%   Computation of the pseudorange correction due to tropospheric refraction.
%   Saastamoinen algorithm.

%----------------------------------------------------------------------------------------------
%                           goGPS v0.4.3
%
% Copyright (C) 2009-2014 Mirko Reguzzoni, Eugenio Realini
%
% Portions of code contributed by Laboratorio di Geomatica, Polo Regionale di Como,
%    Politecnico di Milano, Italy
%----------------------------------------------------------------------------------------------

global tropo_model

%Saastamoinen model requires positive ellipsoidal height
h(h < 0) = 0;

% If in ATM_model section in config file tropo is set to 0 it does not use tropospheric model
% [ATM_model]
% tropo=0
if (tropo_model == 0)
    corr = zeros(size(el));
else
    if (h < 5000)

	%conversion to radians
	el = abs(el) * pi/180;

	    %Standard atmosphere - Berg, 1948 (Bernese)
	%pressure [mbar]
	Pr = 1013.25;
	%temperature [K]
	Tr = 291.15;
	%numerical constants for the algorithm [-] [m] [mbar]
	Hr = 50.0;

	P = Pr * (1-0.0000226*h).^5.225;
	T = Tr - 0.0065*h;
	H = Hr * exp(-0.0006396*h);

	%----------------------------------------------------------------------

	%linear interpolation
	h_a = [0; 500; 1000; 1500; 2000; 2500; 3000; 4000; 5000];
	B_a = [1.156; 1.079; 1.006; 0.938; 0.874; 0.813; 0.757; 0.654; 0.563];

	t = zeros(length(T),1);
	B = zeros(length(T),1);

	for i = 1 : length(T)

	    d = h_a - h(i);
	    [dmin, j] = min(abs(d));
	    if (d(j) > 0)
		index = [j-1; j];
	    else
		index = [j; j+1];
	    end

	    t(i) = (h(i) - h_a(index(1))) ./ (h_a(index(2)) - h_a(index(1)));
	    B(i) = (1-t(i))*B_a(index(1)) + t(i)*B_a(index(2));
	end

	%----------------------------------------------------------------------

	e = 0.01 * H .* exp(-37.2465 + 0.213166*T - 0.000256908*T.^2);

	%tropospheric error
	corr = ((0.002277 ./ sin(el)) .* (P - (B ./ (tan(el)).^2)) + (0.002277 ./ sin(el)) .* (1255./T + 0.05) .* e);
    else
	corr = zeros(size(el));
    end
end
end


function [GPSweek ,GPSsec]=UTC2GPST(app,y,m,d,h,min,s)

if y<90
    y=y+2000;
else
    y=y+1900;
end

if m<=2
    y=y-1;m=m+12;
end
MJD=fix(365.25*y)+fix(30.6001*(m+1))+d+(h+(min+s/60)/60)/24+1720981.5-2400000.5;
GPSweek=fix((MJD-44244)/7);
GPSsec=fix(mod((MJD-44244),7))*86400+h*3600+min*60+s;

end


 % convert ECEF coordinates to local East, North, Up 
%****************************************************************************
% Written by Moein Mehrtash, Concordia University, 3/21/2008                *
% Email: moeinmehrtash@yahoo.com                                            *
%****************************************************************************
% Reference:"GPS Theory and application",edited by B.Parkinson,J.Spilker,   *
%****************************************************************************           
function ENU= XYZ2ENU(app,A,Phi,Lambda) 
  XYZ2ENU0=[-sin(Lambda) cos(Lambda) 0;
           -sin(Phi)*cos(Lambda) -sin(Phi)*sin(Lambda) cos(Phi);
           cos(Phi)*cos(Lambda) cos(Phi)*sin(Lambda)  sin(Phi)];
 
  ENU=XYZ2ENU0*A';       
end


%This Function Compute Azimuth and Elevation of satellite from reciever 
%CopyRight By Moein Mehrtash
%************************************************************************
% Written by Moein Mehrtash, Concordia University, 3/21/2008            *
% Email: moeinmehrtash@yahoo.com                                        *
%************************************************************************           
%    ==================================================================
%    Input :                                                            *
%        Pos_Rcv       : XYZ position of reciever               (Meter) *
%        Pos_SV        : XYZ matrix position of GPS satellites  (Meter) *
%    Output:                                                            *
%        E             :Elevation (Rad)                                 *
%        A             :Azimuth   (Rad)                                 *
%************************************************************************           


function [E,A]=Calc_Azimuth_Elevation(app,Pos_Rcv,Pos_SV)

R=Pos_SV-Pos_Rcv;               %vector from Reciever to Satellite

GPS = ECEF2GPS(app,Pos_Rcv);        %Lattitude and Longitude of Reciever
Lat=GPS(1);Lon=GPS(2);

ENU=XYZ2ENU(app,R,Lat,Lon);
Elevation=asin(ENU(3)/norm(ENU));
Azimuth=atan2(ENU(1)/norm(ENU),ENU(2)/norm(ENU));
E=Elevation;
A=Azimuth;
end


% ECEF2LLA - convert earth-centered earth-fixed (ECEF)
%            cartesian coordinates to latitude, longitude,
%            and altitude
%
% USAGE:
% [lat,lon,alt] = ECEF2GPS(x,y,z)
%
% lat = geodetic latitude (radians)
% lon = longitude (radians)
% alt = height above WGS84 ellipsoid (m)
% x = ECEF X-coordinate (m)
% y = ECEF Y-coordinate (m)
% z = ECEF Z-coordinate (m)
%
% Notes: (1) This function assumes the WGS84 model.
%        (2) Latitude is customary geodetic (not geocentric).
%        (3) Inputs may be scalars, vectors, or matrices of the same
%            size and shape. Outputs will have that same size and shape.
%        (4) Tested but no warranty; use at your own risk.
%        (5) Michael Kleder, April 2006

function GPS = ECEF2GPS(app,Pos)
x=Pos(1);
y=Pos(2);
z=Pos(3);


% WGS84 ellipsoid constants:
a = 6378137;
e = 8.1819190842622e-2;

% calculations:
b   = sqrt(a^2*(1-e^2));
ep  = sqrt((a^2-b^2)/b^2);
p   = sqrt(x^2+y^2);
th  = atan2(a*z,b*p);
lon = atan2(y,x);
lat = atan((z+ep^2*b*(sin(th))^3)/(p-e^2*a*(cos(th))^3));
N   = a/sqrt(1-e^2*(sin(lat))^2);
alt = p/cos(lat)-N;

% correct for numerical instability in altitude near exact poles:
% (after this correction, error is about 2 millimeters, which is about
% the same as the numerical precision of the overall function)

k=abs(x)<1 & abs(y)<1;
alt(k) = abs(z(k))-b;
GPS=[lat,lon,alt];

return

end
        
%This Function approximate Ionospheric Group Delay 这个函数近似电离层群延迟
%CopyRight By Moein Mehrtash
%************************************************************************
% Written by Moein Mehrtash, Concordia University, 3/21/2008            *
% Email: moeinmehrtash@yahoo.com                                        *
%************************************************************************           
% ***********************************************************************           
%      Function for   computing an Ionospheric range correction for the *
%      GPS L1 frequency from the parameters broadcasted in the GPS      *
%      Navigation Message.功能:根据GPS导航信息中广播的参数计算电离层距离修正的GPS L1频率。                                              *
%      ==================================================================
%      References:                                                      *
%      Klobuchar, J.A., (1996) "Ionosphercic Effects on GPS", in        *
%        Parkinson, Spilker (ed), "Global Positioning System Theory and *
%        Applications, pp.513-514.                                      *
%      ICD-GPS-200, Rev. C, (1997), pp. 125-128                         *
%      NATO, (1991), "Technical Characteristics of the NAVSTAR GPS",    *
%        pp. A-6-31   -   A-6-33                                        *
%      ==================================================================
%    Input :                                                            *
%        Pos_Rcv       : XYZ position of reciever               (Meter) *
%        Pos_SV        : XYZ matrix position of GPS satellites  (Meter) *
%        GPS_Time      : Time of Week                           (sec)   *
%        Alfa(4)       : The coefficients of a cubic equation           *
%                        representing the amplitude of the vertical     *
%                        dalay (4 coefficients - 8 bits each)  表示垂直dalay振幅的三次方程的系数(4个系数，每个8位)         *
%        Beta(4)       : The coefficients of a cubic equation           *
%                        representing the period of the model           *
%                        (4 coefficients - 8 bits each) 表示模型周期的三次方程的系数(4个系数-各8位)                *
%    Output:                                                            *
%       Delta_I        : Ionospheric slant range correction for         *
%                        the L1 frequency电离层L1频率的倾斜距离校正(Sec)   *
%     ==================================================================

function [Delta_I]=Error_Ionospheric_Klobuchar(app,Pos_Rcv,Pos_SV,Alpha,Beta,GPS_Time)
GPS_Rcv = ECEF2GPS(app,Pos_Rcv);
% GPS_Rcv = [114.357306,30.5316944,93.4]; 
Lat=GPS_Rcv(1)/pi;Lon=GPS_Rcv(2)/pi;   % semicircles unit Lattitdue and Longitude 
S=size(Pos_SV);
m=S(1);n=S(2);

for i=1:m
[El,A0]=Calc_Azimuth_Elevation(app,Pos_Rcv,Pos_SV(i,:));
E(i)=El/pi;                                            %SemiCircle Elevation
A(i)=A0;                                               %SemiCircle Azimoth 
% Calculate the Earth-Centered angle, Psi
Psi(i)=0.0137/(E(i)+.11)-0.022;                        %SemiCircle

%Compute the Subionospheric lattitude, Phi_L
Phi_L(i)=Lat+Psi(i)*cos(A(i));                         %SemiCircle
if Phi_L(i)>0.416
    Phi_L(i)=0.416;
elseif Phi_L(i)<-0.416
    Phi_L(i)=-0.416;
end

%Compute the subionospheric longitude, Lambda_L
Lambda_L(i)=Lon+(Psi(i)*sin(A(i))/cos(Phi_L(i)*pi));  %SemiCircle

%Find the geomagnetic lattitude ,Phi_m, of the subionospheric location
%looking toward each GPS satellite:
Phi_m(i)=Phi_L(i)+0.064*cos((Lambda_L(i)-1.617)*pi);

%Find the Local Time ,t, at the subionospheric point
t(i)=4.32*10^4*Lambda_L(i)+GPS_Time;                 %GPS_Time(Sec)
t(i)= mod(t(i),86400);
if t(i)>86400
    t(i)=t(i)-86400;
elseif t(i)<0
    t(i)=t(i)+86400;
end

%Convert Slant time delay, Compute the Slant Factor,F
F(i)=1+16*(.53-E(i))^3;

%Compute the ionospheric time delay T_iono by first computing x
Per(i)=Beta(1)+Beta(2)*Phi_m(i)+Beta(3)*Phi_m(i)^2+Beta(4)*Phi_m(i)^3;
if Per(i) <72000                                     %Period
    Per(i)=72000;
end
x(i)=2*pi*(t(i)-50400)/Per(i);                       %Rad
AMP(i)=Alpha(1)+Alpha(2)*Phi_m(i)+Alpha(3)*Phi_m(i)^2+Alpha(4)*Phi_m(i)^3;
if AMP(i)<0 
    AMP(i)=0;
end
if abs(x(i))>1.57
    T_iono(i)=F(i)*5*10^(-9);
else
    T_iono(i)=F(i)*(5*10^(-9)+AMP(i)*(1-x(i)^2/2+x(i)^4/24));
end

end%for

Delta_I=T_iono;
end

    end


    methods (Access = private)

        % Value changed function: IONALPHAEditField
        function IONALPHAEditFieldValueChanged(app, event)
            value = app.IONALPHAEditField.Value;
            
        end

        % Value changed function: IONBETAEditField
        function IONBETAEditFieldValueChanged(app, event)
            value = app.IONBETAEditField.Value;
            
        end

        % Value changed function: ObservablesatelliteEditField
        function ObservablesatelliteEditFieldValueChanged(app, event)
            value = app.ObservablesatelliteEditField.Value;
            
        end

        % Value changed function: PseudorangeDropDown
        function PseudorangeDropDownValueChanged(app, event)
            value = app.PseudorangeDropDown.Value;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            tob(1)=app.yEditField.Value;
            tob(2)=app.mEditField.Value;
            tob(3)=app.dEditField.Value;
            tob(4)=app.hEditField.Value;
            tob(5)=app.mEditField_2.Value;
            tob(6)=app.sEditField.Value;
            geodeticHstation=eval(app.kmGeodeticHstationEditField_2.Value);
            chooseTropo=eval(app.TroposphericmodelDropDown.Value);
            choosePrange=eval(app.PseudorangeDropDown.Value);
            AStr=deblank(app.IONALPHAEditField.Value);BStr=deblank(app.IONBETAEditField.Value);
            AlphaStr=regexp(AStr, '\s+', 'split');BetaStr=regexp(BStr, '\s+', 'split');
            Alpha(1)=eval(AlphaStr{1,1});Alpha(2)=eval(AlphaStr{1,2});Alpha(3)=eval(AlphaStr{1,3});Alpha(4)=eval(AlphaStr{1,4});
            Beta(1)=eval(BetaStr{1,1});Beta(2)=eval(BetaStr{1,2});Beta(3)=eval(BetaStr{1,3});Beta(4)=eval(BetaStr{1,4});
            fileo=app.ofilepathEditField.Value;
            filen=app.nfilepathEditField.Value;
            filem=app.mfilepathEditField.Value;
            [SatUsed,Xr]=fuc_of_Prange_Positioning(app,tob,geodeticHstation,chooseTropo,Alpha,Beta,filen,fileo,filem,choosePrange);
            app.ObservablesatelliteEditField.Value=SatUsed;
            app.XrEditField.Value=Xr(1,1);
            app.YrEditField.Value=Xr(2,1);
            app.ZrEditField.Value=Xr(3,1);
        end

        % Value changed function: TroposphericmodelDropDown
        function TroposphericmodelDropDownValueChanged(app, event)
            value = app.TroposphericmodelDropDown.Value;
            
        end

        % Value changed function: XrEditField
        function XrEditFieldValueChanged(app, event)
            value = app.XrEditField.Value;
            
        end

        % Value changed function: YrEditField
        function YrEditFieldValueChanged(app, event)
            value = app.YrEditField.Value;
            
        end

        % Value changed function: ZrEditField
        function ZrEditFieldValueChanged(app, event)
            value = app.ZrEditField.Value;
            
        end

        % Value changed function: dEditField
        function dEditFieldValueChanged(app, event)
            value = app.dEditField.Value;
            
        end

        % Value changed function: hEditField
        function hEditFieldValueChanged(app, event)
            value = app.hEditField.Value;
            
        end

        % Value changed function: kmGeodeticHstationEditField_2
        function kmGeodeticHstationEditField_2ValueChanged(app, event)
            value = app.kmGeodeticHstationEditField_2.Value;
            
        end

        % Value changed function: mEditField
        function mEditFieldValueChanged(app, event)
            value = app.mEditField.Value;
            
        end

        % Value changed function: mEditField_2
        function mEditField_2ValueChanged(app, event)
            value = app.mEditField_2.Value;
            
        end

        % Value changed function: mfilepathEditField
        function mfilepathEditFieldValueChanged(app, event)
            value = app.mfilepathEditField.Value;
            
        end

        % Value changed function: nfilepathEditField
        function nfilepathEditFieldValueChanged(app, event)
            value = app.nfilepathEditField.Value;
            
        end

        % Value changed function: ofilepathEditField
        function ofilepathEditFieldValueChanged(app, event)
            value = app.ofilepathEditField.Value;
            
        end

        % Value changed function: sEditField
        function sEditFieldValueChanged(app, event)
            value = app.sEditField.Value;
            
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            value = app.yEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.v10UIFigure = uifigure;
            app.v10UIFigure.Name = '伪距单点定位v1.0';
            app.ObservationTimeLabel = uilabel(app.v10UIFigure);
            app.ObservationTimeLabel.Text = '观测时刻(Observation Time)';
            app.yEditFieldLabel = uilabel(app.v10UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.v10UIFigure, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.yEditField.Limits = [0 99];
            app.yEditField.ValueDisplayFormat = '%.0f';
            app.yEditField.Value = 1;
            app.mEditFieldLabel = uilabel(app.v10UIFigure);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.v10UIFigure, 'numeric');
            app.mEditField.ValueChangedFcn = createCallbackFcn(app, @mEditFieldValueChanged, true);
            app.mEditField.Limits = [0 12];
            app.mEditField.ValueDisplayFormat = '%.0f';
            app.mEditField.Value = 9;
            app.dEditFieldLabel = uilabel(app.v10UIFigure);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.v10UIFigure, 'numeric');
            app.dEditField.ValueChangedFcn = createCallbackFcn(app, @dEditFieldValueChanged, true);
            app.dEditField.Limits = [0 31];
            app.dEditField.ValueDisplayFormat = '%.0f';
            app.dEditField.Value = 4;
            app.hEditFieldLabel = uilabel(app.v10UIFigure);
            app.hEditFieldLabel.Text = 'h';
            app.hEditField = uieditfield(app.v10UIFigure, 'numeric');
            app.hEditField.ValueChangedFcn = createCallbackFcn(app, @hEditFieldValueChanged, true);
            app.hEditField.Limits = [0 24];
            app.hEditField.ValueDisplayFormat = '%.0f';
            app.hEditField.Value = 4;
            app.mEditField_2Label = uilabel(app.v10UIFigure);
            app.mEditField_2Label.Text = 'm';
            app.mEditField_2 = uieditfield(app.v10UIFigure, 'numeric');
            app.mEditField_2.ValueChangedFcn = createCallbackFcn(app, @mEditField_2ValueChanged, true);
            app.mEditField_2.Limits = [0 59];
            app.mEditField_2.ValueDisplayFormat = '%.0f';
            app.sEditFieldLabel = uilabel(app.v10UIFigure);
            app.sEditFieldLabel.Text = 's';
            app.sEditField = uieditfield(app.v10UIFigure, 'numeric');
            app.sEditField.ValueChangedFcn = createCallbackFcn(app, @sEditFieldValueChanged, true);
            app.sEditField.Limits = [0 59];
            app.sEditField.ValueDisplayFormat = '%.0f';
            app.ofilepathEditFieldLabel = uilabel(app.v10UIFigure);
            app.ofilepathEditFieldLabel.Text = '观测文件路径(o file path)';
            app.ofilepathEditField = uieditfield(app.v10UIFigure, 'text');
            app.ofilepathEditField.ValueChangedFcn = createCallbackFcn(app, @ofilepathEditFieldValueChanged, true);
            app.ofilepathEditField.Value = 'D:\document\MATLAB_out\GNSS课程\伪距定位\相关文件\程序包终\wuhn2470_clearhdr.01o';
            app.nfilepathEditFieldLabel = uilabel(app.v10UIFigure);
            app.nfilepathEditFieldLabel.Text = '星历文件路径(n file path)';
            app.nfilepathEditField = uieditfield(app.v10UIFigure, 'text');
            app.nfilepathEditField.ValueChangedFcn = createCallbackFcn(app, @nfilepathEditFieldValueChanged, true);
            app.nfilepathEditField.Value = 'D:\document\MATLAB_out\GNSS课程\伪距定位\相关文件\程序包终\wuhn2470_clearhdr.01n';
            app.mfilepathEditFieldLabel = uilabel(app.v10UIFigure);
            app.mfilepathEditFieldLabel.Text = '气象文件路径(m file path)';
            app.mfilepathEditField = uieditfield(app.v10UIFigure, 'text');
            app.mfilepathEditField.ValueChangedFcn = createCallbackFcn(app, @mfilepathEditFieldValueChanged, true);
            app.mfilepathEditField.Value = 'D:\document\MATLAB_out\GNSS课程\伪距定位\相关文件\程序包终\wuhn2470_clearhdr.01m';
            app.TroposphericmodelDropDownLabel = uilabel(app.v10UIFigure);
            app.TroposphericmodelDropDownLabel.Text = '对流层模型Tropospheric model';
            app.TroposphericmodelDropDown = uidropdown(app.v10UIFigure);
            app.TroposphericmodelDropDown.Items = {'Hopfield', 'Saastamoinen'};
            app.TroposphericmodelDropDown.ItemsData = {'1', '2'};
            app.TroposphericmodelDropDown.ValueChangedFcn = createCallbackFcn(app, @TroposphericmodelDropDownValueChanged, true);
            app.TroposphericmodelDropDown.Value = '1';
            app.kmGeodeticHstationEditField_2Label = uilabel(app.v10UIFigure);
            app.kmGeodeticHstationEditField_2Label.Text = '测站大地高(km)GeodeticHstation';
            app.kmGeodeticHstationEditField_2 = uieditfield(app.v10UIFigure, 'text');
            app.kmGeodeticHstationEditField_2.ValueChangedFcn = createCallbackFcn(app, @kmGeodeticHstationEditField_2ValueChanged, true);
            app.kmGeodeticHstationEditField_2.Value = '93.4e-3';
            app.IONALPHAEditFieldLabel = uilabel(app.v10UIFigure);
            app.IONALPHAEditFieldLabel.Text = '电离层ION ALPHA';
            app.IONALPHAEditField = uieditfield(app.v10UIFigure, 'text');
            app.IONALPHAEditField.ValueChangedFcn = createCallbackFcn(app, @IONALPHAEditFieldValueChanged, true);
            app.IONALPHAEditField.Value = '0.2235D-07  0.2235D-07 -0.1192D-06 -0.1192D-06';
            app.IONBETAEditFieldLabel = uilabel(app.v10UIFigure);
            app.IONBETAEditFieldLabel.Text = '电离层ION BETA  ';
            app.IONBETAEditField = uieditfield(app.v10UIFigure, 'text');
            app.IONBETAEditField.ValueChangedFcn = createCallbackFcn(app, @IONBETAEditFieldValueChanged, true);
            app.IONBETAEditField.Value = '0.1290D+06  0.4915D+05 -0.1966D+06  0.3277D+06';
            app.OutputLabel = uilabel(app.v10UIFigure);
            app.OutputLabel.Text = '输出Output';
            app.ObservablesatelliteEditFieldLabel = uilabel(app.v10UIFigure);
            app.ObservablesatelliteEditFieldLabel.Text = '可用卫星Observable satellite';
            app.ObservablesatelliteEditField = uieditfield(app.v10UIFigure, 'text');
            app.ObservablesatelliteEditField.ValueChangedFcn = createCallbackFcn(app, @ObservablesatelliteEditFieldValueChanged, true);
            app.ObservablesatelliteEditField.Editable = 'off';
            app.StationcoordinatesLabel = uilabel(app.v10UIFigure);
            app.StationcoordinatesLabel.Text = '测站坐标Station coordinates';
            app.XrEditFieldLabel = uilabel(app.v10UIFigure);
            app.XrEditFieldLabel.Text = 'Xr';
            app.XrEditField = uieditfield(app.v10UIFigure, 'numeric');
            app.XrEditField.ValueChangedFcn = createCallbackFcn(app, @XrEditFieldValueChanged, true);
            app.XrEditField.ValueDisplayFormat = '%10.3f';
            app.XrEditField.Editable = 'off';
            app.ZrEditFieldLabel = uilabel(app.v10UIFigure);
            app.ZrEditFieldLabel.Text = 'Zr';
            app.ZrEditField = uieditfield(app.v10UIFigure, 'numeric');
            app.ZrEditField.ValueChangedFcn = createCallbackFcn(app, @ZrEditFieldValueChanged, true);
            app.ZrEditField.ValueDisplayFormat = '%10.3f';
            app.ZrEditField.Editable = 'off';
            app.YrEditFieldLabel = uilabel(app.v10UIFigure);
            app.YrEditFieldLabel.Text = 'Yr';
            app.YrEditField = uieditfield(app.v10UIFigure, 'numeric');
            app.YrEditField.ValueChangedFcn = createCallbackFcn(app, @YrEditFieldValueChanged, true);
            app.YrEditField.ValueDisplayFormat = '%10.3f';
            app.YrEditField.Editable = 'off';
            app.StartButton = uibutton(app.v10UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.unitmLabel = uilabel(app.v10UIFigure);
            app.unitmLabel.Text = 'unit : m';
            app.TextArea = uitextarea(app.v10UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'contct : https://github.com/pycppmath/-Pseudo---distance-single-point-positioning.git'};
            app.PseudorangeDropDownLabel = uilabel(app.v10UIFigure);
            app.PseudorangeDropDownLabel.Text = '伪距Pseudorange';
            app.PseudorangeDropDown = uidropdown(app.v10UIFigure);
            app.PseudorangeDropDown.Items = {'C/A', 'P1', 'P2'};
            app.PseudorangeDropDown.ItemsData = {'1', '2', '3'};
            app.PseudorangeDropDown.ValueChangedFcn = createCallbackFcn(app, @PseudorangeDropDownValueChanged, true);
            app.PseudorangeDropDown.Value = '1';
            app.NotesTextAreaLabel = uilabel(app.v10UIFigure);
            app.NotesTextAreaLabel.Text = '说明Notes：';
            app.NotesTextArea = uitextarea(app.v10UIFigure);
            app.NotesTextArea.Editable = 'off';
            app.NotesTextArea.Value = {'1.使用的文件必须去除头文件。'; '2.测站大地高未知时，填入NaN。'; '3.采用Saastamoinen模型时，气象文件路径可为空。'; '4.电离层参数按示例方式输入，直接复制自导航文件。'; '5.计算时间稍长，约20秒。'; '1.Files used must be header files removed.'; '2.When the geodetic height of the surveying station is unknown, fill in NaN.'; '3.When use the model of Saastamoinen, m file path can be empty.'; '4.Ionospheric parameters are entered as examples and copied directly from the navigation file.'; '5.It takes a little longer, about 20 seconds.'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Prange_Positioning

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.v10UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.v10UIFigure)
        end
    end
end